classdef RyersonKickstageFlightSoftware < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WEditField                     matlab.ui.control.EditField
        WEditFieldLabel                matlab.ui.control.Label
        VEditField                     matlab.ui.control.EditField
        VEditFieldLabel                matlab.ui.control.Label
        AEditField                     matlab.ui.control.EditField
        AEditFieldLabel                matlab.ui.control.Label
        EditField                      matlab.ui.control.EditField
        EditFieldLabel                 matlab.ui.control.Label
        hPaEditField                   matlab.ui.control.EditField
        hPaEditFieldLabel              matlab.ui.control.Label
        CEditField                     matlab.ui.control.EditField
        CEditFieldLabel                matlab.ui.control.Label
        TimeKSEditField                matlab.ui.control.EditField
        TimeKSEditFieldLabel           matlab.ui.control.Label
        TimeGSEditField                matlab.ui.control.EditField
        TimeGSEditFieldLabel           matlab.ui.control.Label
        TelemetryLabel                 matlab.ui.control.Label
        PowerGauge                     matlab.ui.control.Gauge
        PowerGaugeLabel                matlab.ui.control.Label
        CurrentGauge                   matlab.ui.control.Gauge
        CurrentGaugeLabel              matlab.ui.control.Label
        VoltageGauge                   matlab.ui.control.Gauge
        VoltageGaugeLabel              matlab.ui.control.Label
        BatteryLifeGauge               matlab.ui.control.Gauge
        BatteryLifeLabel               matlab.ui.control.Label
        PressureGauge                  matlab.ui.control.Gauge
        PressureGaugeLabel             matlab.ui.control.Label
        TemperatureGauge               matlab.ui.control.Gauge
        TemperatureGaugeLabel          matlab.ui.control.Label
        PORTEditField                  matlab.ui.control.NumericEditField
        PORTEditFieldLabel             matlab.ui.control.Label
        HOSTEditField                  matlab.ui.control.EditField
        HOSTEditFieldLabel             matlab.ui.control.Label
        DisconnectfromKickstageButton  matlab.ui.control.Button
        RyersonKickstageGroundStationLabel  matlab.ui.control.Label
        PitchSlider                    matlab.ui.control.Slider
        PitchSliderLabel               matlab.ui.control.Label
        YawSlider                      matlab.ui.control.Slider
        YawSliderLabel                 matlab.ui.control.Label
        RollSlider                     matlab.ui.control.Slider
        RollSliderLabel                matlab.ui.control.Label
        AutopilotButton                matlab.ui.control.Button
        ManualOverrideButton           matlab.ui.control.Button
        MonitorADropDown               matlab.ui.control.DropDown
        MonitorADropDownLabel          matlab.ui.control.Label
        ConnecttoKickstageButton       matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        r
        data
        connectionStatus
        Acknowledge
        Monitor
        rExist
        stop
        cam % Description
        MonitorA % Description
        MonitorB % Description
        h % Description
        startTime % Description
        LiveView % Description
        HOST % Description
        PORT % Description
        RollAng % Description
        s1 % Description
        s2 % Description
        i % Description
        PitchAng % Description
    end
    
    methods (Access = private)
    end
    
    methods (Access = public)
        function gatherData(app)             
                tcpclient = tcpip(app.HOST,...
                    app.PORT, 'Timeout', 600,...
                    'OutputBufferSize',1024,'InputBufferSize',1024);
                fopen(tcpclient);
                fwrite(tcpclient,'Transmit');
                    while(1) 
                        nBytes = get(tcpclient,'BytesAvailable');
                        if nBytes>0
                            break;
                        end
                    end
                receive = fread(tcpclient,nBytes);
                app.data=str2num(char(receive(2:end-1)'));
                
                app.Acknowledge = app.data(end);
                app.TemperatureGauge.Value = app.data(6);
                app.PressureGauge.Value = app.data(7);
                app.BatteryLifeGauge.Value = app.data(11);
                app.VoltageGauge.Value = app.data(8);
                app.CurrentGauge.Value = app.data(9);
                app.PowerGauge.Value = app.data(10);
              
                fclose(tcpclient);       
        end
        function plot2Monitors(app)
            if app.MonitorA == 1
                app.stop = false;
                while ~app.stop
                    gatherData(app);
                    videoLiveStream(app);                
                    timeKeeping(app);
                    telemetryOutput(app);
                    plotAttitudeA(app);
                    
                end                
            elseif app.MonitorA == 2
                app.stop = false;
                while ~app.stop
                    gatherData(app);
                    timeKeeping(app);
                    telemetryOutput(app);
                    plotAttitudeA(app);
                    videoLiveStream(app);
                end                 
            end
        end
        
        function plotAttitudeA(app)
            %Monitor = app.UIAxes;
            q0 = double(app.data(2));
            q1 = double(app.data(3));
            q2 = double(app.data(4));
            q3 = double(app.data(5));
            q = [q0,q1,q2,q3];
            
            DCM = quat2dcm(q);
            n1 = inv(DCM)*[1;0;0];
            n2 = inv(DCM)*[0;1;0];
            n3 = inv(DCM)*[0;0;1];
            % Plotting 
            clf(app.UIAxes)
            plot3(app.UIAxes,[-1 0 0],[0 0 0],[0 0 0],[0 0 0],...
                [0 -1 0],[0 0 0],[0 0 0],[0 0 0],[0 0 -1],'LineWidth',2)
            xlim(app.UIAxes,[-1 1])
            ylim(app.UIAxes,[-1 1])
            zlim(app.UIAxes,[-1 1])
            hold(app.UIAxes,'on');
            % Body Frame
            X = n1;
            x1 = linspace(0,X(1));
            x2 = linspace(0,X(2));
            x3 = linspace(0,X(3));
            Y = n2;
            y1 = linspace(0,Y(1)).*-1;
            y2 = linspace(0,Y(2)).*-1;
            y3 = linspace(0,Y(3)).*-1;
            Z = n3;
            z1 = linspace(0,Z(1)).*-1;
            z2 = linspace(0,Z(2)).*-1;
            z3 = linspace(0,Z(3)).*-1;
            plot3(app.UIAxes, x1,x2,x3,'-.',y1,y2,y3,'-.',...
                z1,z2,z3,'-.','LineWidth',2)
            legend(app.UIAxes,'East','North','Down','b1','b2','b3')
            grid(app.UIAxes, 'on');
            hold(app.UIAxes,'off');
            drawnow;
        end
                
        function plotAttitudeB(app)

        end
        
        function plotTrajectoryA(app)
            
        end
        
        function plotTrajectoryB(app)
            
        end
        
        function videoLiveStream(app)
            img = snapshot(app.cam);
            imagesc(app.UIAxes_2, img);
            drawnow; 

        end

        function connect2pi(app)
%CONNECT2PI will initiate connection to raspberry pi, and will connect 
%MATLAB client to Python server

%Inputs are the HOST and PORT Numbers: Both variable must be strings
            
            if app.rExist == true
                disp("Connection Reestablished");
            else
            clear app.cam
            clear app.r
            app.r=raspi(app.HOST,'pi','peachmango'); %School
            app.s1 = servo(app.r, 18, 'MinPulseDuration',7.00e-4,'MaxPulseDuration',2.3e-3);
            app.s2 = servo(app.r, 23, 'MinPulseDuration',7.00e-4,'MaxPulseDuration',2.3e-3);
            app.cam = cameraboard(app.r, 'Resolution', '1920x1080', 'FrameRate', 60); %'160x120','1024x768'
            %app.cam = webcam(app.r, 'mmal service 16.1 (platform:bcm2835-v4l2-0)', '160x120') %'160x120'
            app.cam.ImageEffect = 'none';
            end
            tcpclient = tcpip(app.HOST, app.PORT, 'Timeout',...
                600,'OutputBufferSize',1024,'InputBufferSize',1024);
            fopen(tcpclient);
            
            fwrite(tcpclient,'Transmit');
                while(1) 
                    nBytes = get(tcpclient,'BytesAvailable');
                    if nBytes>0
                        break;
                    end
                end
            receive = fread(tcpclient,nBytes);
            app.data=str2num(char(receive(2:end-1)'));
            app.Acknowledge = app.data(end);
            fclose(tcpclient);            
        end
        
        function disconnect2raspi(app)
%DISCONNECT2PI will disconnect from the raspberry pi and will terminate the
%TCP connection between the MATLAB client and Python Server. Prior to
%disconnecting, all the relevant data will first be downloaded into Ground
%Station.           
            
            tcpclient = tcpip(app.HOST, app.PORT, 'Timeout',...
                600,'OutputBufferSize',1024,'InputBufferSize',1024);
            fopen(tcpclient);
            
            fwrite(tcpclient,'Disconnecting');
                while(1) 
                    nBytes = get(tcpclient,'BytesAvailable');
                    if nBytes>0
                        break;
                    end
                end
            receive = fread(tcpclient,nBytes);
            app.data=str2num(char(receive(2:end-1)'));
            disconnectStatus = app.data(end);
            
            getFile(app.r, '/home/pi/senseHat/*.txt',...
                'C:\Users\janncristobal\OneDrive - Ryerson University\Winter 2022\AER 813 Group B Task 3\Prototyping\Graphical User Interface\DataLogs')
        end
        
        function timeKeeping(app)
            timeKS = num2str(app.data(1));
            timeGS = datetime('now','Format','HH:mm:ss.SSS');
            app.TimeKSEditField.Value = timeKS;
            app.TimeGSEditField.Value = string(timeGS);
            
        end
        
        function telemetryOutput(app)
           app.CEditField.Value = num2str(app.data(6));
           app.hPaEditField.Value = num2str(app.data(7));
           app.EditField.Value = num2str(app.data(11));
           app.VEditField.Value = num2str(app.data(8));
           app.AEditField.Value = num2str(app.data(9));
           app.WEditField.Value = num2str(app.data(10));
        end
        
        function servoControl(app)
            writePosition(app.s1,app.RollAng);  
        end
        
        function servoControlAuto(app)
            writePosition(app.s1,app.i)
            writePosition(app.s2,app.i)
        end
        
        function plot2MonitorsAuto(app)
            if app.MonitorA == 1
                app.stop = false;
                for i = 90:-5:45
                    app.i = i;
                    servoControlAuto(app);
                    pause(0.5);
                    gatherData(app);
                    videoLiveStream(app);                
                    timeKeeping(app);
                    telemetryOutput(app);
                    plotAttitudeA(app);
                    pause(0.5)
                end
                for i = 45:5:135
                    app.i = i;
                    servoControlAuto(app);
                    pause(0.5);
                    gatherData(app);
                    videoLiveStream(app);                
                    timeKeeping(app);
                    telemetryOutput(app);
                    plotAttitudeA(app);
                    pause(0.5)
                end
                for i = 135:-5:90
                    app.i = i;
                    servoControlAuto(app);
                    pause(0.5);
                    gatherData(app);
                    videoLiveStream(app);                
                    timeKeeping(app);
                    telemetryOutput(app);
                    plotAttitudeA(app);
                    pause(0.5)
                end
                app.stop = false;
                while ~app.stop
                    gatherData(app);
                    videoLiveStream(app);                
                    timeKeeping(app);
                    telemetryOutput(app);
                    plotAttitudeA(app);
                end  
            elseif app.MonitorA == 2
                app.stop = false;
                while ~app.stop
                    gatherData(app);
                    timeKeeping(app);
                    telemetryOutput(app);
                    plotAttitudeA(app);
                    videoLiveStream(app);
                end                 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.AutopilotButton, 'Enable', 'off');    
            set(app.ManualOverrideButton, 'Enable', 'off');
            set(app.DisconnectfromKickstageButton, 'Enable', 'off');    
            set(app.RollSlider, 'Enable', 'off');
            set(app.PitchSlider, 'Enable', 'off');    
            set(app.YawSlider, 'Enable', 'off');
            set(app.MonitorADropDown, 'Enable', 'off');    
        end

        % Button pushed function: ConnecttoKickstageButton
        function ConnecttoKickstageButtonPushed(app, event)
            app.ConnecttoKickstageButton.Text = "Connecting to Kickstage ...";
            app.ConnecttoKickstageButton.BackgroundColor = [0.93,0.69,0.13];            
            app.HOST = string(app.HOSTEditField.Value);
            app.PORT = double(app.PORTEditField.Value);
            connect2pi(app);
            app.connectionStatus = 1;
                       
            if app.Acknowledge == 1
                app.ConnecttoKickstageButton.Text = "Established Connection";
                app.ConnecttoKickstageButton.BackgroundColor = [0,1,0];
                app.DisconnectfromKickstageButton.Text = "Bitconnect";
                app.DisconnectfromKickstageButton.BackgroundColor =[1,1,1];
                set(app.AutopilotButton, 'Enable', 'on');
                set(app.ManualOverrideButton, 'Enable', 'on');
                set(app.DisconnectfromKickstageButton, 'Enable', 'on');
            else
                app.ConnecttoKickstageButton.Text = "Connection Failed";
                app.ConnecttoKickstageButton.BackgroundColor = [1,0,0];
                set(app.AutopilotButton, 'Enable', 'off');
                set(app.ManualOverrideButton, 'Enable', 'off');
                set(app.DisconnectfromKickstageButton, 'Enable', 'off');
            end

        end

        % Button pushed function: DisconnectfromKickstageButton
        function DisconnectfromKickstageButtonPushed(app, event)
            FileName = "recordedData_"+ datestr(now,'dd-mm-yyyy_HH:MM');
            FolderName = "DataLogs";
            
%             disconnectStatus = 0;
%             pause(3);
%             disconnectStatus = 1;
            app.rExist = true;
            app.stop = true;
            %disconnectStatus = disconnect2pi(app.HOST,app.PORT,app);
            disconnect2raspi(app);
%            if disconnectStatus == 0
                app.DisconnectfromKickstageButton.Text = "Disconnecting";
                app.DisconnectfromKickstageButton.BackgroundColor =...
                    [1.00,1.00,0.07];
                app.ConnecttoKickstageButton.Text = "Connect to Kickstage";
                app.ConnecttoKickstageButton.BackgroundColor = [1,1,1];
            pause(3);
%            else
                app.DisconnectfromKickstageButton.Text = "Disconnected";
                app.DisconnectfromKickstageButton.BackgroundColor =...
                    [0.07,0.62,1];
                app.ConnecttoKickstageButton.Text = "Connect to Kickstage";
                app.ConnecttoKickstageButton.BackgroundColor = [1,1,1];
                set(app.AutopilotButton, 'Enable', 'off');
                set(app.ManualOverrideButton, 'Enable', 'off');
                set(app.RollSlider, 'Enable', 'off');
                set(app.PitchSlider, 'Enable', 'off');    
                set(app.YawSlider, 'Enable', 'off');
                set(app.MonitorADropDown, 'Enable', 'off');
                app.connectionStatus = 0;
           % end
        end

        % Value changed function: HOSTEditField
        function HOSTEditFieldValueChanged(app, event)
            value = app.HOSTEditField.Value;
            
        end

        % Callback function
        function PORTEditFieldValueChanged(app, event)
            value = app.PORTTextArea.Value;
            
        end

        % Button pushed function: AutopilotButton
        function AutopilotButtonPushed(app, event)

            app.AutopilotButton.Text = "Autopilot: Active";
            app.AutopilotButton.BackgroundColor = [0.07,0.62,1];
            app.ManualOverrideButton.Text = "Manual Override: Inactive";
            app.ManualOverrideButton.BackgroundColor = [1.00,1.00,0.07];
            set(app.RollSlider, 'Enable', 'off');
            set(app.PitchSlider, 'Enable', 'off');    
            set(app.YawSlider, 'Enable', 'off');
            set(app.MonitorADropDown, 'Enable', 'on');
            
            app.stop = true;

            %Monitor A
            switch app.MonitorADropDown.Value
                case 'Attitude'
                    app.MonitorA = 1;
                    app.UIAxes.Title.String = 'Body-Frame wrt NED-Frame';
                case 'Trajectory'
                    app.MonitorA = 2;
                    app.UIAxes.Title.String = ['Kickstage Trajectory' ...
                        ' in ECI-Frame'];
            end
            plot2MonitorsAuto(app);
 
            
            % call a function to plot attitude on Monitor A 
            % call a function to plot attitude on Monitor B
        end

        % Button pushed function: ManualOverrideButton
        function ManualOverrideButtonPushed(app, event)
            app.stop = true;
            app.AutopilotButton.Text = "Autopilot: Inactive";
            app.AutopilotButton.BackgroundColor = [1.00,1.00,0.07];
            app.ManualOverrideButton.Text = "Manual Override: Active";
            app.ManualOverrideButton.BackgroundColor = [0.07,0.62,1];
            set(app.RollSlider, 'Enable', 'on');
            set(app.PitchSlider, 'Enable', 'on');    
            set(app.YawSlider, 'Enable', 'on');
            set(app.MonitorADropDown, 'Enable', 'on');
            
            app.RollAng = (app.RollSlider.Value)+90;
            app.PitchAng = (app.PitchSlider.Value)+90;
            writePosition(app.s1,app.RollAng);
            writePosition(app.s2,app.PitchAng);

            %Monitor A
            switch app.MonitorADropDown.Value
                case 'Attitude'
                    app.MonitorA = 1;
                    app.UIAxes.Title.String = 'Body-Frame wrt NED-Frame';
                case 'Trajectory'
                    app.MonitorA = 2;
                    app.UIAxes.Title.String = ['Kickstage Trajectory' ...
                        ' in ECI-Frame'];
            end
            plot2Monitors(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Monitor A')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Monitor B')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ConnecttoKickstageButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoKickstageButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoKickstageButtonPushed, true);
            app.ConnecttoKickstageButton.Text = 'Connect to Kickstage';
            app.MonitorADropDownLabel = uilabel(app.UIFigure);
            app.MonitorADropDownLabel.Text = 'Monitor A';
            app.MonitorADropDown = uidropdown(app.UIFigure);
            app.MonitorADropDown.Items = {'Attitude', 'Trajectory'};
            app.MonitorADropDown.Value = 'Attitude';
            app.ManualOverrideButton = uibutton(app.UIFigure, 'push');
            app.ManualOverrideButton.ButtonPushedFcn = createCallbackFcn(app, @ManualOverrideButtonPushed, true);
            app.ManualOverrideButton.Text = 'Manual Override';
            app.AutopilotButton = uibutton(app.UIFigure, 'push');
            app.AutopilotButton.ButtonPushedFcn = createCallbackFcn(app, @AutopilotButtonPushed, true);
            app.AutopilotButton.Text = 'Autopilot';
            app.RollSliderLabel = uilabel(app.UIFigure);
            app.RollSliderLabel.Text = 'Roll';
            app.RollSlider = uislider(app.UIFigure);
            app.RollSlider.Limits = [-45 45];
            app.RollSlider.MajorTicks = [-90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90];
            app.RollSlider.MinorTicks = [-90 -89 -88 -87 -86 -85 -84 -83 -82 -81 -80 -79 -78 -77 -76 -75 -74 -73 -72 -71 -70 -69 -68 -67 -66 -65 -64 -63 -62 -61];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
            app.YawSliderLabel = uilabel(app.UIFigure);
            app.YawSliderLabel.Text = 'Yaw';
            app.YawSlider = uislider(app.UIFigure);
            app.YawSlider.Limits = [-45 45];
            app.YawSlider.MajorTicks = [-90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90];
            app.YawSlider.MinorTicks = [-90 -89 -88 -87 -86 -85 -84 -83 -82 -81 -80 -79 -78 -77 -76 -75 -74 -73 -72 -71 -70 -69 -68 -67 -66 -65 -64 -63 -62 -61];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
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.UIFigure);
            app.PitchSlider.Limits = [-45 45];
            app.PitchSlider.MajorTicks = [-90 -85 -80 -75 -70 -65 -60 -55 -50 -45 -40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90];
            app.PitchSlider.MinorTicks = [-90 -89 -88 -87 -86 -85 -84 -83 -82 -81 -80 -79 -78 -77 -76 -75 -74 -73 -72 -71 -70 -69 -68 -67 -66 -65 -64 -63 -62 -61];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
            app.RyersonKickstageGroundStationLabel = uilabel(app.UIFigure);
            app.RyersonKickstageGroundStationLabel.Text = 'Ryerson Kickstage Ground Station';
            app.DisconnectfromKickstageButton = uibutton(app.UIFigure, 'push');
            app.DisconnectfromKickstageButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectfromKickstageButtonPushed, true);
            app.DisconnectfromKickstageButton.Text = 'Disconnect from Kickstage';
            app.HOSTEditFieldLabel = uilabel(app.UIFigure);
            app.HOSTEditFieldLabel.Text = 'HOST';
            app.HOSTEditField = uieditfield(app.UIFigure, 'text');
            app.HOSTEditField.ValueChangedFcn = createCallbackFcn(app, @HOSTEditFieldValueChanged, true);
            app.HOSTEditField.Value = '192.168.137.71';
            app.PORTEditFieldLabel = uilabel(app.UIFigure);
            app.PORTEditFieldLabel.Text = 'PORT';
            app.PORTEditField = uieditfield(app.UIFigure, 'numeric');
            app.PORTEditField.Value = 9975;
            app.TemperatureGaugeLabel = uilabel(app.UIFigure);
            app.TemperatureGaugeLabel.Text = 'Temperature';
            app.TemperatureGauge = uigauge(app.UIFigure, 'circular');
            app.TemperatureGauge.Limits = [-10 50];
            app.TemperatureGauge.MajorTicks = [-10 0 10 20 30 40 50];
            app.PressureGaugeLabel = uilabel(app.UIFigure);
            app.PressureGaugeLabel.Text = 'Pressure';
            app.PressureGauge = uigauge(app.UIFigure, 'circular');
            app.PressureGauge.Limits = [900 1100];
            app.PressureGauge.MajorTicks = [900 950 1000 1050 1100];
            app.BatteryLifeLabel = uilabel(app.UIFigure);
            app.BatteryLifeLabel.Text = 'BatteryLife';
            app.BatteryLifeGauge = uigauge(app.UIFigure, 'circular');
            app.VoltageGaugeLabel = uilabel(app.UIFigure);
            app.VoltageGaugeLabel.Text = 'Voltage';
            app.VoltageGauge = uigauge(app.UIFigure, 'circular');
            app.VoltageGauge.Limits = [0 8];
            app.VoltageGauge.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.CurrentGaugeLabel = uilabel(app.UIFigure);
            app.CurrentGaugeLabel.Text = 'Current';
            app.CurrentGauge = uigauge(app.UIFigure, 'circular');
            app.CurrentGauge.Limits = [-5 5];
            app.CurrentGauge.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.PowerGaugeLabel = uilabel(app.UIFigure);
            app.PowerGaugeLabel.Text = 'Power';
            app.PowerGauge = uigauge(app.UIFigure, 'circular');
            app.PowerGauge.Limits = [0 5];
            app.PowerGauge.MajorTicks = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.PowerGauge.MinorTicks = [-5 -4.9 -4.8 -4.7 -4.6 -4.5 -4.4 -4.3 -4.2 -4.1 -4 -3.9 -3.8 -3.7 -3.6 -3.5 -3.4 -3.3 -3.2 -3.1 -3 -2.9 -2.8 -2.7 -2.6 -2.5 -2.4 -2.3 -2.2 -2.1 -2 -1.9 -1.8 -1.7 -1.6 -1.5 -1.4 -1.3 -1.2 -1.1 -1 -0.899999999999999 -0.8 -0.7 -0.6 -0.5 -0.399999999999999 -0.3 -0.199999999999999 -0.0999999999999996 0 0.0999999999999996 0.199999999999999 0.3 0.399999999999999 0.5 0.6 0.7 0.8 0.899999999999999 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4 4.1 4.2 4.3 4.4 4.5 4.6 4.7 4.8 4.9 5];
            app.TelemetryLabel = uilabel(app.UIFigure);
            app.TelemetryLabel.Text = 'Telemetry';
            app.TimeGSEditFieldLabel = uilabel(app.UIFigure);
            app.TimeGSEditFieldLabel.Text = 'Time GS';
            app.TimeGSEditField = uieditfield(app.UIFigure, 'text');
            app.TimeKSEditFieldLabel = uilabel(app.UIFigure);
            app.TimeKSEditFieldLabel.Text = 'Time KS';
            app.TimeKSEditField = uieditfield(app.UIFigure, 'text');
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.UIFigure, 'text');
            app.hPaEditFieldLabel = uilabel(app.UIFigure);
            app.hPaEditFieldLabel.Text = 'hPa';
            app.hPaEditField = uieditfield(app.UIFigure, 'text');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '%';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'text');
            app.VEditFieldLabel = uilabel(app.UIFigure);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.UIFigure, 'text');
            app.WEditFieldLabel = uilabel(app.UIFigure);
            app.WEditFieldLabel.Text = 'W';
            app.WEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RyersonKickstageFlightSoftware

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end